classdef NiemczykSulot2017_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Application       matlab.ui.Figure
        OpenImage         matlab.ui.control.Button
        Zrange            matlab.ui.container.Panel
        DNAL              matlab.ui.control.NumericEditField
        Toolbox           matlab.ui.container.Panel
        ZoominButton      matlab.ui.control.Button
        ZoomoutButton     matlab.ui.control.Button
        MeasureButton     matlab.ui.control.Button
        DeleteButton      matlab.ui.control.Button
        MarkPointsButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end


    properties (Access = public)
        xlim
        ylim % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible = 'off';
            app.xlim = 0;
            app.ylim = 0;
        end

        % Button pushed function: OpenImage
        function OpenImageCallback(app, event)
            global img
            global cmap
            global header
            
            [file,path] = uigetfile('*.*');
            file= fullfile(path, file);
            [img, header, errmsg] = readImage(file);
            %app.EditField.Value = errmsg;
            
            app.UIAxes.reset
            % Display image and stretch to fill axes
            imagesc(img, 'Parent', app.UIAxes);
            cmap = AdvancedColormap('AFM');
            cmap = cmap(1:(end-16),:);
            colormap( app.UIAxes,  cmap);
            colorbar( app.UIAxes);

            
            %,...
            %'XData', [1 app.UIAxes.Position(3)], ...
            %'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 (size(img,2))];
            app.UIAxes.YLim = [0 (size(img,1))];
            app.xlim = app.UIAxes.XLim;
            app.ylim = app.UIAxes.YLim;
            app.UIAxes.Visible = 'on';
            
        end

        % Button pushed function: ZoominButton
        function ZoomInCallback(app, event)
            zoom(app.UIAxes, 'on');
            %now clicking/scrolling in the figure will zoom in
            %zoom(app.UIAxes, 'off')

        end

        % Button pushed function: ZoomoutButton
        function ZoomOutCallback(app, event)
            zoom(app.UIAxes, 'off');
             app.UIAxes.XLim = app.xlim;
             app.UIAxes.YLim = app.ylim;
        end

        % Button pushed function: MeasureButton
        function MeasureCallback(app, event)
            global img
            global cmap 
            global header
            [img_crop, xs,ys, DNALength] = Measure(img,cmap, header);
            imagesc(img_crop, 'Parent', app.UIAxes);
            hold(app.UIAxes);           
            colormap( app.UIAxes,  cmap );
            colorbar( app.UIAxes);
            plot(xs,ys,'.r','Parent',app.UIAxes, 'MarkerSize', 10);
            
            maxx = (size(img_crop,2));
            maxy = (size(img_crop,1));
            
             app.UIAxes.XLim = [0,maxx];
             app.UIAxes.YLim = [0,maxy];
             app.xlim = app.UIAxes.XLim;
             app.ylim = app.UIAxes.YLim;
             %DNALength = round(DNALength);
             app.DNAL.Value = double(DNALength);
             app.UIAxes.Visible = 'on';

         
        end

        % Button pushed function: DeleteButton
        function DeleteCallback(app, event)
          close all
        end

        % Button pushed function: MarkPointsButton
        function MarkPoints(app, event)
            global img
            global cmap
            global x
            global y
            global h
            
            figure(1); 
            imagesc(img); hold on;
            colormap(cmap);
            
            PointsNumber = 2;
            x = zeros(1, PointsNumber);
            y = zeros(1, PointsNumber);
            for i = 1:PointsNumber
                 [x(i), y(i)] = ginput(1);
                 h(i) = plot(x(i),y(i),'.r', 'MarkerSize', 10);
            end
            hold off;
            
            figure(2);
            app.UIAxes;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Application = uifigure;
            app.Application.Name = 'Niemczyk & Sulot v 2.0';
            app.OpenImage = uibutton(app.Application, 'push');
            app.OpenImage.ButtonPushedFcn = createCallbackFcn(app, @OpenImageCallback, true);
            app.OpenImage.Text = 'Open Image';
            app.Zrange = uipanel(app.Application);
            app.Zrange.Title = 'DNA Length [nm]';
            app.DNAL = uieditfield(app.Zrange, 'numeric');
            app.Toolbox = uipanel(app.Application);
            app.Toolbox.Title = 'Toolbox';
            app.ZoominButton = uibutton(app.Toolbox, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInCallback, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.Toolbox, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutCallback, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.MeasureButton = uibutton(app.Toolbox, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureCallback, true);
            app.MeasureButton.Text = 'Measure';
            app.DeleteButton = uibutton(app.Toolbox, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCallback, true);
            app.DeleteButton.Text = 'Delete';
            app.MarkPointsButton = uibutton(app.Toolbox, 'push');
            app.MarkPointsButton.ButtonPushedFcn = createCallbackFcn(app, @MarkPoints, true);
            app.MarkPointsButton.Text = 'Mark Points';
            app.UIAxes = uiaxes(app.Application);
        end
    end

    methods (Access = public)

        % Construct app
        function app = NiemczykSulot2017_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Application)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Application)
        end
    end
end